classdef dsc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        title                   matlab.ui.control.Label
        start_button            matlab.ui.control.Button
        cancel_button           matlab.ui.control.Button
        input_paramters         matlab.ui.container.Panel
        rho_input               matlab.ui.control.EditField
        r2_star_input           matlab.ui.control.EditField
        TR_input                matlab.ui.control.EditField
        TE_input                matlab.ui.control.EditField
        Psvd_input              matlab.ui.control.EditField
        text5                   matlab.ui.control.Label
        text6                   matlab.ui.control.Label
        text7                   matlab.ui.control.Label
        text8                   matlab.ui.control.Label
        text9                   matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        text16                  matlab.ui.control.Label
        text17                  matlab.ui.control.Label
        species_selection       matlab.ui.container.ButtonGroup
        mouse_button            matlab.ui.control.RadioButton
        human_button            matlab.ui.control.RadioButton
        image_selection         matlab.ui.container.Panel
        dsc_image_path          matlab.ui.control.EditField
        remove_images           matlab.ui.control.Button
        add_images              matlab.ui.control.Button
        define_aif              matlab.ui.container.Panel
        aif_roi_path_gui        matlab.ui.control.EditField
        add_aif_roi             matlab.ui.control.Button
        aif_selection           matlab.ui.container.ButtonGroup
        auto_cluster            matlab.ui.control.RadioButton
        user_aif                matlab.ui.control.RadioButton
        import_aif_button       matlab.ui.control.RadioButton
        Import_stored           matlab.ui.control.RadioButton
        import_aif_path_gui     matlab.ui.control.EditField
        add_import_aif          matlab.ui.control.Button
        status                  matlab.ui.control.Label
        noise_handling          matlab.ui.container.Panel
        noise_selection         matlab.ui.container.ButtonGroup
        auto_Noise              matlab.ui.control.RadioButton
        user_Noise              matlab.ui.control.RadioButton
        add_noise_roi           matlab.ui.control.Button
        noise_roi_path_gui      matlab.ui.control.EditField
        fitting_function_group  matlab.ui.container.ButtonGroup
        forcelb                 matlab.ui.control.RadioButton
        rawdata                 matlab.ui.control.RadioButton
        biexp                   matlab.ui.control.RadioButton
        biexl                   matlab.ui.control.RadioButton
        Gammav                  matlab.ui.control.RadioButton
        upslopebiexp            matlab.ui.control.RadioButton
        upslopebiexpliadj       matlab.ui.control.RadioButton
        forcebiexp              matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        dsc_image           % path to raw DSC images
        AIF_type            % integer specifying aif type
        Fitting_function    % integer specifying fitting function
        noise_type          % integer specify noise type
        aif_roi_path        % path to AIF roi file
        import_aif_path     % path to matlab file with AIF
        noise_roi_path      % path to noise roi file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DSC_gui_OpeningFcn(app, varargin)
            uirestore(app.TE_input,struct('prop','Value'))
            uirestore(app.TR_input,struct('prop','Value'))
            uirestore(app.Psvd_input,struct('prop','Value'))
            uirestore(app.r2_star_input,struct('prop','Value'))
            uirestore(app.rho_input,struct('prop','Value'))
        end

        % Selection changed function: fitting_function_group
        function fitting_function_group_SelectionChangedFcn(app, event)
            switch get(event.NewValue,'Tag') % Get Tag of selected object.
                case 'forcelb'
                    app.Fitting_function =  0;
                case 'biexp'
                    app.Fitting_function = 1;
                case 'biexl'
                    app.Fitting_function = 2;
                case 'Gammav'
                    app.Fitting_function = 3;
                case 'rawdata'
                    app.Fitting_function = 4;
                case 'upslopebiexp'
                    app.Fitting_function = 5;
                case 'upslopebiexpliadj'
                    app.Fitting_function = 6;
                case 'forcebiexp'
                    app.Fitting_function = 7;
            end
        end

        % Value changed function: Psvd_input
        function Psvd_input_Callback(app, event)
            uiremember(app.Psvd_input,struct('prop','Value'));
        end

        % Button pushed function: start_button
        function start_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Start_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Unboxing the variables from the handles structure:
            TE = str2double(get(app.TE_input,'Value'))/1000.0; %conversion from miliseconds to seconds 12/15/17
            TR = str2double(get(app.TR_input,'Value'));%/1000.0;
            deltaT = TR / 60;  % converstion to minutes.
            r2_star = str2double(get(app.r2_star_input,'Value'));
            species = app.species_selection.SelectedObject.Tag;
            Psvd = str2double(get(app.Psvd_input,'Value'));
            rho =  str2double(get(app.rho_input,'Value'));
            %AIF_type = handles.AIF_type;
            %Fitting_function = handles.Fitting_function;
            %noise_type = handles.noise_type;
            %details = whos('r2_star');
            
            %Next WE ARE GOING TO LOAD THE IMAGE FILE:
            disp(app.dsc_image);
            disp(species);
            disp(r2_star);
            image_array = load_nii(app.dsc_image);
            image_array = image_array.img;
            %Next we load the nosie roi array:
            if app.noise_type == 0
                roi_array = [];
            elseif app.noise_type == 1
                roi_array = load_nii(app.noise_roi_path);
                roi_array = roi_array.img;
            end
            
            
            %getting the path from the image file:
            [ ~ , image_path] = fileparts(app.dsc_image);
            
            [concentration_array, base_concentration_array, time_vect, base_time_vect,whole_time_vect, bolus_time] = DSC_signal2concentration(image_array,TE,TR,r2_star,species,image_path,app.noise_type, ...
               roi_array );
            if app.AIF_type == 0 %AIF Auto
                [meanAIF, meanSignal] = AIF_auto_cluster(concentration_array, image_array, time_vect, TR,species);
                baseline = 0; %develop a way to calculate the baseline from the auto clustered AIF
                baseline_array = zeros(numel(base_time_vect));
            elseif app.AIF_type ==1 %AIF User Selected
                AIF_mask = load_nii(app.aif_roi_path);
                AIF_mask = AIF_mask.img;
                [meanAIF, meanSignal, baseline,baseline_array] = AIF_manual_noreshape(image_array,concentration_array,AIF_mask, base_time_vect,base_concentration_array);
            
            elseif app.AIF_type==2 %AIF Import
                load(app.import_aif_path)
                [meanAIF_adjusted, time_vect, concentration_array] = import_AIF(meanAIF, bolus_time, time_vect, concentration_array, r2_star, TE);
                meanAIF = meanAIF_adjusted;
            
                display('time_vect_num')
                numel(time_vect)
                display('meanAIF_num')
                numel(meanAIF)
            
            elseif app.AIF_type==3 %AIF Use Previous
                load('previous_data.mat');
                [meanAIF_adjusted, time_vect, concentration_array] = previous_AIF(meanAIF,meanSignal,bolus_time, time_vect,concentration_array);
                meanAIF = meanAIF_adjusted;
            
                display('time_vect_num')
                numel(time_vect)
                display('meanAIF_num')
                numel(meanAIF)
            end
            
            %create a mean AIF spaning the whole scan time
            AIF_whole = cat(1,baseline_array, meanAIF);
            
            %now run the selected fitting function
            if app.Fitting_function == 0 %forced linear biexponential (uses local max) %the upslope is fitted to
            
                Cp = cat(1,baseline_array,meanAIF);
                step = [(bolus_time) (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Single_Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif app.Fitting_function == 1 %biexponential (uses absolute max)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelp(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif app.Fitting_function == 2 %biexponential (uses local max)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif app.Fitting_function == 3 %gamma-variant
                % Now we fit the AIF with a SCR model:
            
                %assigning the gamma variate function, gfun, to be our desired fitting
                %function:
                Ct = fitting_gamma_variant(meanAIF,species, time_vect);
                Cp = cat(1,baseline_array, Ct); %Cp is created for plotting purposes only. Ct is analyzed for CBF, CBV...
            
            elseif app.Fitting_function == 4 %raw data
                Ct = meanAIF;
                Cp = cat(1,baseline_array, Ct);
            
            %{
            elseif app.Fitting_function == 42 %copy_of_upslopewith peak based decision making (not currently an option)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                [~,max_indexCt] = max(Ct);
            
                if numel(findpeaks(AIF_whole(bolus_time:((bolus_time + 1) + max_indexCt)))) == 1 %check for additional local maxima in upslope, if none
                    %are present use the exact upslope vales for the fitted funciton
                    Ct(1:max_indexCt) = meanAIF(1: max_indexCt);
                    Ct = Ct';
                    base = baseline * ones(numel(baseline_array),1);
                    Cp = [base; Ct];
                else %if additional local maxima or 'bumps' in the upslope are present, use the 2 point forced linear fit
                    Ct = Ct';
                end
            %}
            elseif app.Fitting_function == 5 %copy_of_upslope with peak based decision making
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time -1) = [];
            
               %calculate the most likely first peak by finding the first local maxima
                %as was done in the local fitting help function
                %peak is the first within 3% of the max value of the whole data set
                [local_maxima, maxima_indexes] = findpeaks(Ct);
                maxima_iterator = 1;
                while(local_maxima(maxima_iterator) < (0.97 * max(local_maxima)))
                    maxima_iterator = maxima_iterator + 1;
                end
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                if numel(findpeaks(AIF_whole(bolus_time:(bolus_time + max_indexCt)))) == 1 %check for additional local maxima in upslope, if none
                    %are present use the exact upslope vales for the fitted funciton
                    %[bolus time, max_index + 1] is the range that is examined for the
                    %additional maxima
                    Ct(1:max_indexCt) = meanAIF(1:max_indexCt);
                    Ct = Ct';
                    base = baseline * ones(numel(baseline_array),1);
                    Cp = [base; Ct];
                else %if additional local maxima or 'bumps' in the upslope are present, use a smooth fit
                    Ct = Ct';
                end
            
            elseif app.Fitting_function == 6 %upslope copy biexponetial
            
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
            
                %calculate the most likely first peak by finding the first local maxima
                %as was done in the local fitting help function
                %peak is the first within 3% of the max value of the whole data set
                [local_maxima, maxima_indexes] = findpeaks(Ct);
                maxima_iterator = 1;
                while(local_maxima(maxima_iterator) < (0.97 * max(local_maxima)))
                    maxima_iterator = maxima_iterator + 1;
                end
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                %exact upslope vales for the fitted funciton
                Ct(1:max_indexCt) = meanAIF(1:max_indexCt);
                Ct = Ct';
                base = baseline * ones(numel(baseline_array),1);
                Cp = [base; Ct];
            
            elseif app.Fitting_function == 7 %forced bilinear biexponential decay
            
                %two linear function
                Cp = cat(1,baseline_array,meanAIF);
                step = [(bolus_time) (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            end
            
            %save this runs meanAIF, bolus time, and importedAIF
            save('previous_data.mat', 'meanAIF','meanSignal','bolus_time','baseline');
            
            %plot the meanAIF and fitted function over the entire scan
            figure;
            plot(whole_time_vect,Cp,'-o', whole_time_vect, AIF_whole);
            title('AIF and Fitted AIF Over time');
            xlabel('Time (min)');
            ylabel('Concentration (mM)');
            legend('Fitted AIF', 'Raw AIF');
            
            %plot the mean AIF and fitted function over the time period to be analyzed
            figure;
            time_vect_sec = time_vect * 60; %get time in seconds to plot
            time_vect_sec = time_vect_sec + (bolus_time);
            %base_time_vect_sec = base_time_vect * 60;
            plot(time_vect_sec,Ct,'-o',time_vect_sec,meanAIF);
            title('AIF and Fitted AIF Over time');
            xlabel('Time (s)');
            ylabel('Concentration (mM)');
            legend('Fitted AIF', 'Raw AIF');
            
            %plot the mean AIF signal
            figure;
            time_vect_sec2 = 0 : length(meanSignal) -1;
            time_vect_sec2 = time_vect_sec2 * TR;
            plot(time_vect_sec2, meanSignal, 'b');
            title('AIF Mean Signal from Start to End of Scan')
            xlabel('Time (s)')
            ylabel('Signal Intensity (au)')
            
            Kh = 0.71;
            % method = 1;
            [CBF, CBV, MTT] = DSC_convolution_sSVD(concentration_array,Ct,deltaT,Kh,rho,Psvd,1,image_path);
            disp('new')
        end

        % Value changed function: TE_input
        function TE_input_Callback(app, event)
            uiremember(app.TE_input,struct('prop','Value'));
        end

        % Value changed function: TR_input
        function TR_input_Callback(app, event)
            uiremember(app.TR_input,struct('prop','Value'));
        end

        % Button pushed function: add_aif_roi
        function add_aif_roi_Callback(app, event)
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.aif_roi_path_gui.Value = visualpath;
                app.aif_roi_path = path_out;
            
            end
        end

        % Button pushed function: add_images
        function add_images_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(app.dsc_image_path,'Value',visualpath);
                app.dsc_image = path_out;
            
            end
            
            %guidata(hObject, handles);
        end

        % Button pushed function: add_import_aif
        function add_import_aif_Callback(app, event)
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.m','Matlab Files (*.m)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.import_aif_path_gui.Value = visualpath;
                app.import_aif_path = path_out;
            end
        end

        % Button pushed function: add_noise_roi
        function add_noise_roi_Callback(app, event)
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.noise_roi_path_gui.Value = visualpath;
                app.noise_roi_path = path_out;
            end
        end

        % Selection changed function: aif_selection
        function aif_selection_SelectionChangeFcn(app, event)

            switch get(event.NewValue,'Tag') % Get Tag of selected object.
                case 'auto_cluster'
                   app.AIF_type =  0;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = false;
                   app.add_import_aif.Enable = false;
                case 'user_aif'
                   app.AIF_type = 1;
                   app.aif_roi_path_gui.Enable = true;
                   app.add_aif_roi.Enable = true;
                   app.import_aif_path_gui.Enable = false;
                   app.add_import_aif.Enable = false;
                case 'import_aif'
                   app.AIF_type = 2;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = true;
                   app.add_import_aif.Enable = true;
                case 'import_stored'
                   app.AIF_type = 3;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = true;
                   app.add_import_aif.Enable = true;
            end
        end

        % Value changed function: noise_roi_path_gui
        function noise_roi_path_gui_Callback(app, event)

        end

        % Selection changed function: noise_selection
        function noise_selection_SelectionChangeFcn(app, event)
            switch get(event.NewValue,'Tag') % Get Tag of selected object.
                case 'auto_Noise'
                    app.noise_type =  0;
                    app.noise_roi_path_gui.Enable = false;
                    app.add_noise_roi.Enable = false;
                case 'user_Noise'
                    app.noise_type = 1;
                    app.noise_roi_path_gui.Enable = true;
                    app.add_noise_roi.Enable = true;
            end
        end

        % Value changed function: r2_star_input
        function r2_star_input_Callback(app, event)
            uiremember(app.r2_star_input,struct('prop','Value'));
        end

        % Button pushed function: remove_images
        function remove_images_Callback(app, event)
            app.dsc_image_path.Value = '';
            app.dsc_image = [];
        end

        % Value changed function: rho_input
        function rho_input_Callback(app, event)
            uiremember(app.rho_input,struct('prop','Value'));
        end

        % Selection changed function: species_selection
        function species_selection_SelectionChangeFcn(app, event)

        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

            %uiremember(app.TE_input,struct('prop','Value'))
            delete(app)
        end

        % Button pushed function: cancel_button
        function cancel_buttonButtonPushed(app, event)
            closereq()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DSC_gui';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.title = uilabel(app.figure1);
            app.title.Tag = 'text3';
            app.title.Text = 'DSC Analysis GUI';
            app.start_button = uibutton(app.figure1, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_button_Callback, true);
            app.start_button.Tag = 'Start_button';
            app.start_button.Text = 'Start Analysis';
            app.cancel_button = uibutton(app.figure1, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_buttonButtonPushed, true);
            app.cancel_button.Tag = 'Cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.input_paramters = uipanel(app.figure1);
            app.input_paramters.Title = 'Input Parameters';
            app.input_paramters.Tag = 'uipanel3';
            app.rho_input = uieditfield(app.input_paramters, 'text');
            app.rho_input.ValueChangedFcn = createCallbackFcn(app, @rho_input_Callback, true);
            app.rho_input.Tag = 'rho_input';
            app.rho_input.Value = '1';
            app.r2_star_input = uieditfield(app.input_paramters, 'text');
            app.r2_star_input.ValueChangedFcn = createCallbackFcn(app, @r2_star_input_Callback, true);
            app.r2_star_input.Tag = 'r2_star_input';
            app.r2_star_input.Value = '1.05';
            app.TR_input = uieditfield(app.input_paramters, 'text');
            app.TR_input.ValueChangedFcn = createCallbackFcn(app, @TR_input_Callback, true);
            app.TR_input.Tag = 'TR_input';
            app.TR_input.Value = '1';
            app.TE_input = uieditfield(app.input_paramters, 'text');
            app.TE_input.ValueChangedFcn = createCallbackFcn(app, @TE_input_Callback, true);
            app.TE_input.Tag = 'TE_input';
            app.TE_input.Value = '19';
            app.Psvd_input = uieditfield(app.input_paramters, 'text');
            app.Psvd_input.ValueChangedFcn = createCallbackFcn(app, @Psvd_input_Callback, true);
            app.Psvd_input.Tag = 'Psvd_input';
            app.Psvd_input.Value = '0.005';
            app.text5 = uilabel(app.input_paramters);
            app.text5.Tag = 'text5';
            app.text5.Text = 'TE';
            app.text6 = uilabel(app.input_paramters);
            app.text6.Tag = 'text6';
            app.text6.Text = 'TR';
            app.text7 = uilabel(app.input_paramters);
            app.text7.Tag = 'text7';
            app.text7.Text = 'R2*';
            app.text8 = uilabel(app.input_paramters);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Rho';
            app.text9 = uilabel(app.input_paramters);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Psvd';
            app.text14 = uilabel(app.input_paramters);
            app.text14.Tag = 'text14';
            app.text14.Text = 'ms';
            app.text15 = uilabel(app.input_paramters);
            app.text15.Tag = 'text15';
            app.text15.Text = 'ms';
            app.text16 = uilabel(app.input_paramters);
            app.text16.Tag = 'text16';
            app.text16.Text = 'L/(mmol·s)';
            app.text17 = uilabel(app.input_paramters);
            app.text17.Tag = 'text17';
            app.text17.Text = 'g/ml';
            app.species_selection = uibuttongroup(app.input_paramters);
            app.species_selection.SelectionChangedFcn = createCallbackFcn(app, @species_selection_SelectionChangeFcn, true);
            app.species_selection.Title = 'Species';
            app.species_selection.Tag = 'species_selection';
            app.mouse_button = uiradiobutton(app.species_selection);
            app.mouse_button.Tag = 'mouse';
            app.mouse_button.Text = 'Mouse';
            app.mouse_button.Value = true;
            app.human_button = uiradiobutton(app.species_selection);
            app.human_button.Tag = 'human';
            app.human_button.Text = 'Human';
            app.image_selection = uipanel(app.figure1);
            app.image_selection.Title = 'Image Selection';
            app.image_selection.Tag = 'uipanel8';
            app.dsc_image_path = uieditfield(app.image_selection, 'text');
            app.dsc_image_path.Tag = 'dsc_image_path';
            app.dsc_image_path.Value = 'DSC Image Path (.nii files only)';
            app.remove_images = uibutton(app.image_selection, 'push');
            app.remove_images.ButtonPushedFcn = createCallbackFcn(app, @remove_images_Callback, true);
            app.remove_images.Tag = 'remove_images';
            app.remove_images.Text = 'Remove Image';
            app.add_images = uibutton(app.image_selection, 'push');
            app.add_images.ButtonPushedFcn = createCallbackFcn(app, @add_images_Callback, true);
            app.add_images.Tag = 'add_images';
            app.add_images.Text = 'Add Image';
            app.define_aif = uipanel(app.figure1);
            app.define_aif.Title = 'Define AIF';
            app.define_aif.Tag = 'uipanel12';
            app.aif_roi_path_gui = uieditfield(app.define_aif, 'text');
            app.aif_roi_path_gui.Tag = 'AIF_path';
            app.aif_roi_path_gui.Enable = 'off';
            app.aif_roi_path_gui.Value = 'AIF Filepath (.nii files only; same size as Image)';
            app.add_aif_roi = uibutton(app.define_aif, 'push');
            app.add_aif_roi.ButtonPushedFcn = createCallbackFcn(app, @add_aif_roi_Callback, true);
            app.add_aif_roi.Tag = 'add_AIF_roi';
            app.add_aif_roi.Enable = 'off';
            app.add_aif_roi.Text = 'Select AIF ROI file (or enter below)';
            app.aif_selection = uibuttongroup(app.define_aif);
            app.aif_selection.SelectionChangedFcn = createCallbackFcn(app, @aif_selection_SelectionChangeFcn, true);
            app.aif_selection.Title = 'AIF Selection Type';
            app.aif_selection.Tag = 'aif_selection';
            app.auto_cluster = uiradiobutton(app.aif_selection);
            app.auto_cluster.Tag = 'auto_cluster';
            app.auto_cluster.Text = 'Auto Cluster AIF';
            app.auto_cluster.Value = true;
            app.user_aif = uiradiobutton(app.aif_selection);
            app.user_aif.Tag = 'user_aif';
            app.user_aif.Text = 'User Selected AIF';
            app.import_aif_button = uiradiobutton(app.aif_selection);
            app.import_aif_button.Tag = 'import_aif';
            app.import_aif_button.Text = 'Import AIF';
            app.Import_stored = uiradiobutton(app.aif_selection);
            app.Import_stored.Tag = 'Import_stored';
            app.Import_stored.Enable = 'off';
            app.Import_stored.Text = 'Import Previously Stored AIF';
            app.import_aif_path_gui = uieditfield(app.define_aif, 'text');
            app.import_aif_path_gui.Enable = 'off';
            app.import_aif_path_gui.Value = 'AIF Filepath (.m file)';
            app.add_import_aif = uibutton(app.define_aif, 'push');
            app.add_import_aif.ButtonPushedFcn = createCallbackFcn(app, @add_import_aif_Callback, true);
            app.add_import_aif.Tag = 'add_AIF_roi';
            app.add_import_aif.Enable = 'off';
            app.add_import_aif.Text = 'Select Import AIF file (or enter below)';
            app.status = uilabel(app.figure1);
            app.status.Tag = 'text21';
            app.status.Text = 'Polymerizing Soon...';
            app.noise_handling = uipanel(app.figure1);
            app.noise_handling.Title = 'Noise Handling';
            app.noise_handling.Tag = 'uipanel14';
            app.noise_selection = uibuttongroup(app.noise_handling);
            app.noise_selection.SelectionChangedFcn = createCallbackFcn(app, @noise_selection_SelectionChangeFcn, true);
            app.noise_selection.Title = 'Noise Region Selection';
            app.noise_selection.Tag = 'noise_selection';
            app.auto_Noise = uiradiobutton(app.noise_selection);
            app.auto_Noise.Tag = 'auto_Noise';
            app.auto_Noise.Text = 'Automatic';
            app.auto_Noise.Value = true;
            app.user_Noise = uiradiobutton(app.noise_selection);
            app.user_Noise.Tag = 'user_Noise';
            app.user_Noise.Text = 'User Defined';
            app.add_noise_roi = uibutton(app.noise_handling, 'push');
            app.add_noise_roi.ButtonPushedFcn = createCallbackFcn(app, @add_noise_roi_Callback, true);
            app.add_noise_roi.Tag = 'add_noise_roi';
            app.add_noise_roi.Enable = 'off';
            app.add_noise_roi.Text = 'Select Noise ROI file';
            app.noise_roi_path_gui = uieditfield(app.noise_handling, 'text');
            app.noise_roi_path_gui.ValueChangedFcn = createCallbackFcn(app, @noise_roi_path_gui_Callback, true);
            app.noise_roi_path_gui.Tag = 'noise_file';
            app.noise_roi_path_gui.Enable = 'off';
            app.noise_roi_path_gui.Value = 'Noise File (.nii; same size as image)';
            app.fitting_function_group = uibuttongroup(app.figure1);
            app.fitting_function_group.SelectionChangedFcn = createCallbackFcn(app, @fitting_function_group_SelectionChangedFcn, true);
            app.fitting_function_group.Title = 'Fitting_Function';
            app.fitting_function_group.Tag = 'Fitting_function';
            app.forcelb = uiradiobutton(app.fitting_function_group);
            app.forcelb.Tag = 'forcelb';
            app.forcelb.Text = 'Forced linear biexponential';
            app.rawdata = uiradiobutton(app.fitting_function_group);
            app.rawdata.Tag = 'rawdata';
            app.rawdata.Text = 'Raw Data';
            app.biexp = uiradiobutton(app.fitting_function_group);
            app.biexp.Tag = 'biexp';
            app.biexp.Text = 'Biexponential';
            app.biexl = uiradiobutton(app.fitting_function_group);
            app.biexl.Tag = 'biexl';
            app.biexl.Text = 'Biexponential local';
            app.Gammav = uiradiobutton(app.fitting_function_group);
            app.Gammav.Tag = 'Gammav';
            app.Gammav.Text = 'Gamma-Variant';
            app.Gammav.Value = true;
            app.upslopebiexp = uiradiobutton(app.fitting_function_group);
            app.upslopebiexp.Tag = 'upslopebiexp';
            app.upslopebiexp.Text = 'Upslope copy biexponential';
            app.upslopebiexpliadj = uiradiobutton(app.fitting_function_group);
            app.upslopebiexpliadj.Tag = 'upslopebiexpliadj';
            app.upslopebiexpliadj.Text = 'Upslope copy biexponential linear adjustment';
            app.forcebiexp = uiradiobutton(app.fitting_function_group);
            app.forcebiexp.Tag = 'forcebiexp';
            app.forcebiexp.Text = 'Forced bilinear exponential';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)DSC_gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end